classdef siscan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        AcquisitionTab            matlab.ui.container.Tab
        SINGLESHOTDSCANLabel      matlab.ui.control.Label
        InitializeButton          matlab.ui.control.Button
        SingleImageButton         matlab.ui.control.Button
        ExitCameraButton          matlab.ui.control.Button
        ExposuremsSpinnerLabel    matlab.ui.control.Label
        ExposuremsSpinner         matlab.ui.control.Spinner
        UIAxes                    matlab.ui.control.UIAxes
        FramerateSpinnerLabel     matlab.ui.control.Label
        FramerateSpinner          matlab.ui.control.Spinner
        ColormapDropDownLabel     matlab.ui.control.Label
        ColormapDropDown          matlab.ui.control.DropDown
        CONTROLSLabel             matlab.ui.control.Label
        INDICATORSLabel           matlab.ui.control.Label
        ExposuremsEditFieldLabel  matlab.ui.control.Label
        ExposuremsEditField       matlab.ui.control.NumericEditField
        LiveViewButton            matlab.ui.control.StateButton
        SaveScanButton            matlab.ui.control.Button
        TreatmentTab              matlab.ui.container.Tab
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        LoadScanButton            matlab.ui.control.Button
        UseMeasuredButton         matlab.ui.control.Button
        UIAxes4                   matlab.ui.control.UIAxes
        LoadScanningButton        matlab.ui.control.Button
        LoadSpectrumButton        matlab.ui.control.Button
        DATATREATMENTLabel        matlab.ui.control.Label
        CalibrateButton           matlab.ui.control.Button
        RetrievalTab              matlab.ui.container.Tab
        THERETRIEVALLabel         matlab.ui.control.Label
        UIAxes5                   matlab.ui.control.UIAxes
        UIAxes6                   matlab.ui.control.UIAxes
        RetrieveButton            matlab.ui.control.Button
        HextEditFieldLabel        matlab.ui.control.Label
        HextEditField             matlab.ui.control.NumericEditField
        LoadFundamentalButton     matlab.ui.control.Button
        LextEditFieldLabel        matlab.ui.control.Label
        LextEditField             matlab.ui.control.NumericEditField
        TweakParametersLabel      matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Label_4                   matlab.ui.control.Label
    end


    properties (Access = private)
        
        IDScam; % Camera class
        img % measured image class
        vid %for live input
        cmp %colormap class
        ld  %loaded image class
        scan % scan class
        fnspec %fundamental spectrum
        wlcab % wavelength calibration file
        zcab % glass insertion calibration file
    end


    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)

                app.InitializeButton.FontColor = 'green';
                app.IDScam = IDSCamera('4103393584');
                app.IDScam.init();
                app.ExposuremsSpinner.Enable = 'on';
                app.IDScam.setColorMode('Mono8') %sets the colormode
                
        end

        % Button pushed function: SingleImageButton
        function SingleImageButtonPushed(app, event)
                app.IDScam.getImageAsyncStartCam();
                app.img = app.IDScam.getImageAsyncEndCam();
                imshow(app.img,'Parent',app.UIAxes,'colormap',app.cmp)
        end

        % Button pushed function: ExitCameraButton
        function ExitCameraButtonPushed(app, event)
            app.IDScam.delete();
            app.InitializeButton.FontColor = 'k';
            
        end

        % Value changed function: ExposuremsSpinner
        function ExposuremsSpinnerValueChanged(app, event)
            value = app.ExposuremsSpinner.Value;
            app.IDScam.setExposure(value*1e-3)
            fprintf('Exposure set to %.1f ms.\n',value)
            expTime = getExposure(app.IDScam);
            app.ExposuremsEditField.Value = expTime*1e3;
        end

        % Value changed function: LiveViewButton
        function LiveViewButtonValueChanged(app, event)
        
            
   %live view through .net implementation         
             app.IDScam.startLiveView();
             app.LiveViewButton.FontColor = 'r';
             value = app.LiveViewButton.Value;
             
             while value
                 if ~value
                      app.IDScam.stopLiveView();
                      
                     break
                 end
                app.img = app.IDScam.extractImage(); 
                imshow(app.img,'Parent',app.UIAxes,'colormap',app.cmp);  
%            
                pause(1e-5);
                value = app.LiveViewButton.Value;
             end
              app.LiveViewButton.FontColor = 'k';
%             
        end

        % Value changed function: FramerateSpinner
        function FramerateSpinnerValueChanged(app, event)
            value = app.FramerateSpinner.Value;
            app.IDScam.setFramerate(value)
            fprintf('Framerate set to %.1f Hz.\n',value)
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            switch value
                case 'Monochrome'
                    app.cmp = colormap(app.UIAxes,gray);
                case 'Jet'
                    app.cmp = colormap(app.UIAxes,jet);
                case 'Hot'
                    app.cmp = colormap(app.UIAxes,hot);
                case 'Winter'
                    app.cmp = colormap(app.UIAxes,winter);
            end
        end

        % Button pushed function: SaveScanButton
        function SaveScanButtonPushed(app, event)
            filter = {'*.png';'*.jpg';'*.fig';'*.bmp';'*.*'};
            [file,path] = uiputfile(filter,'Save Scan');
            
            if isequal(file,0) || isequal(path,0)
                 disp('Cancelled.')
            else
              disp('Dscan saved')
            end
            dscan = fullfile(path,file);
            imwrite(app.img,jet(255),dscan);
            
            
        end

        % Button pushed function: LoadScanButton
        function LoadScanButtonPushed(app, event)

            filter = {'*.fig';'*.png';'*.jpg'};
            [file,path] = uigetfile(filter);
            if isequal(file,0)
                disp('Cancelled')
            else
                disp(['User selected ',fullfile(path,file)])
            end
            app.ld = imread(fullfile(path,file));
            
            %here, the generalized function for treatment should be written
            % binning and wavelength - > frequency
            
            app.scan = app.ld;
            imshow(app.scan,'Parent',app.UIAxes2,'colormap',app.cmp); 
        end

        % Button pushed function: LoadSpectrumButton
        function LoadSpectrumButtonPushed(app, event)


        end

        % Button pushed function: UseMeasuredButton
        function UseMeasuredButtonPushed(app, event)
            
            % here, the generalized function for treatment should be written
            % binning and wavelength - > frequency
            
            app.scan = app.img;
            imshow(app.scan,'Parent',app.UIAxes2,'colormap',app.cmp); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.SINGLESHOTDSCANLabel = uilabel(app.AcquisitionTab);
            app.SINGLESHOTDSCANLabel.Text = 'SINGLE-SHOT DSCAN';
            app.InitializeButton = uibutton(app.AcquisitionTab, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.SingleImageButton = uibutton(app.AcquisitionTab, 'push');
            app.SingleImageButton.ButtonPushedFcn = createCallbackFcn(app, @SingleImageButtonPushed, true);
            app.SingleImageButton.Text = 'Single Image';
            app.ExitCameraButton = uibutton(app.AcquisitionTab, 'push');
            app.ExitCameraButton.ButtonPushedFcn = createCallbackFcn(app, @ExitCameraButtonPushed, true);
            app.ExitCameraButton.Text = 'Exit Camera';
            app.ExposuremsSpinnerLabel = uilabel(app.AcquisitionTab);
            app.ExposuremsSpinnerLabel.Text = 'Exposure, ms';
            app.ExposuremsSpinner = uispinner(app.AcquisitionTab);
            app.ExposuremsSpinner.ValueChangedFcn = createCallbackFcn(app, @ExposuremsSpinnerValueChanged, true);
            app.UIAxes = uiaxes(app.AcquisitionTab);
            title(app.UIAxes, 'Scan')
            app.FramerateSpinnerLabel = uilabel(app.AcquisitionTab);
            app.FramerateSpinnerLabel.Text = 'Framerate';
            app.FramerateSpinner = uispinner(app.AcquisitionTab);
            app.FramerateSpinner.ValueChangedFcn = createCallbackFcn(app, @FramerateSpinnerValueChanged, true);
            app.ColormapDropDownLabel = uilabel(app.AcquisitionTab);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.AcquisitionTab);
            app.ColormapDropDown.Items = {'Monochrome', 'Jet', 'Hot', 'Winter'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Monochrome';
            app.CONTROLSLabel = uilabel(app.AcquisitionTab);
            app.CONTROLSLabel.Text = 'CONTROLS';
            app.INDICATORSLabel = uilabel(app.AcquisitionTab);
            app.INDICATORSLabel.Text = 'INDICATORS';
            app.ExposuremsEditFieldLabel = uilabel(app.AcquisitionTab);
            app.ExposuremsEditFieldLabel.Text = 'Exposure, ms';
            app.ExposuremsEditField = uieditfield(app.AcquisitionTab, 'numeric');
            app.ExposuremsEditField.Editable = 'off';
            app.LiveViewButton = uibutton(app.AcquisitionTab, 'state');
            app.LiveViewButton.ValueChangedFcn = createCallbackFcn(app, @LiveViewButtonValueChanged, true);
            app.LiveViewButton.Text = 'Live View';
            app.SaveScanButton = uibutton(app.AcquisitionTab, 'push');
            app.SaveScanButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScanButtonPushed, true);
            app.SaveScanButton.Text = 'Save Scan';
            app.TreatmentTab = uitab(app.TabGroup);
            app.TreatmentTab.Title = 'Treatment';
            app.UIAxes2 = uiaxes(app.TreatmentTab);
            title(app.UIAxes2, 'SIScan')
            xlabel(app.UIAxes2, '\omega, rad/fs')
            ylabel(app.UIAxes2, 'Glass insertion')
            app.UIAxes3 = uiaxes(app.TreatmentTab);
            title(app.UIAxes3, 'Spectrum')
            xlabel(app.UIAxes3, '\omega, rad/fs')
            ylabel(app.UIAxes3, 'Intensity, a.u.')
            app.LoadScanButton = uibutton(app.TreatmentTab, 'push');
            app.LoadScanButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScanButtonPushed, true);
            app.LoadScanButton.Text = 'Load Scan';
            app.UseMeasuredButton = uibutton(app.TreatmentTab, 'push');
            app.UseMeasuredButton.ButtonPushedFcn = createCallbackFcn(app, @UseMeasuredButtonPushed, true);
            app.UseMeasuredButton.Text = 'Use Measured';
            app.UIAxes4 = uiaxes(app.TreatmentTab);
            title(app.UIAxes4, 'Scanning Scan')
            xlabel(app.UIAxes4, '\omega, rad/fs')
            ylabel(app.UIAxes4, 'Glass')
            app.LoadScanningButton = uibutton(app.TreatmentTab, 'push');
            app.LoadScanningButton.Text = 'Load Scanning';
            app.LoadSpectrumButton = uibutton(app.TreatmentTab, 'push');
            app.LoadSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpectrumButtonPushed, true);
            app.LoadSpectrumButton.Text = 'Load Spectrum';
            app.DATATREATMENTLabel = uilabel(app.TreatmentTab);
            app.DATATREATMENTLabel.Text = 'DATA TREATMENT';
            app.CalibrateButton = uibutton(app.TreatmentTab, 'push');
            app.CalibrateButton.Text = 'Calibrate';
            app.RetrievalTab = uitab(app.TabGroup);
            app.RetrievalTab.Title = 'Retrieval';
            app.THERETRIEVALLabel = uilabel(app.RetrievalTab);
            app.THERETRIEVALLabel.Text = 'THE RETRIEVAL';
            app.UIAxes5 = uiaxes(app.RetrievalTab);
            title(app.UIAxes5, 'Measured')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes6 = uiaxes(app.RetrievalTab);
            title(app.UIAxes6, 'Retrieved')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.RetrieveButton = uibutton(app.RetrievalTab, 'push');
            app.RetrieveButton.Text = 'Retrieve!';
            app.HextEditFieldLabel = uilabel(app.RetrievalTab);
            app.HextEditFieldLabel.Text = 'Hext';
            app.HextEditField = uieditfield(app.RetrievalTab, 'numeric');
            app.LoadFundamentalButton = uibutton(app.RetrievalTab, 'push');
            app.LoadFundamentalButton.Text = 'Load Fundamental';
            app.LextEditFieldLabel = uilabel(app.RetrievalTab);
            app.LextEditFieldLabel.Text = 'Lext';
            app.LextEditField = uieditfield(app.RetrievalTab, 'numeric');
            app.TweakParametersLabel = uilabel(app.RetrievalTab);
            app.TweakParametersLabel.Text = 'Tweak Parameters';
            app.Label = uilabel(app.RetrievalTab);
            app.Label.Text = '1.';
            app.Label_2 = uilabel(app.RetrievalTab);
            app.Label_2.Text = '2.';
            app.Label_3 = uilabel(app.RetrievalTab);
            app.Label_3.Text = '3.';
            app.Label_4 = uilabel(app.RetrievalTab);
            app.Label_4.Text = '4.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = siscan

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end